classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MeanErrorEditField        matlab.ui.control.NumericEditField
        MeanErrorEditFieldLabel   matlab.ui.control.Label
        RSSEditField              matlab.ui.control.NumericEditField
        RSSEditFieldLabel         matlab.ui.control.Label
        SequenceButtonGroup       matlab.ui.container.ButtonGroup
        IPCOnButton               matlab.ui.control.ToggleButton
        WarmUpButton              matlab.ui.control.ToggleButton
        TempMatchButton           matlab.ui.control.ToggleButton
        FSNLButton                matlab.ui.control.ToggleButton
        SyncButton                matlab.ui.control.ToggleButton
        FiringButton              matlab.ui.control.ToggleButton
        PurgeButton               matlab.ui.control.ToggleButton
        InitiateButton            matlab.ui.control.ToggleButton
        TimeSavedsEditField       matlab.ui.control.NumericEditField
        TimeSavedsEditFieldLabel  matlab.ui.control.Label
        StartupTypeButtonGroup    matlab.ui.container.ButtonGroup
        HotButton                 matlab.ui.control.ToggleButton
        Warm2Button               matlab.ui.control.ToggleButton
        Warm1Button               matlab.ui.control.ToggleButton
        ColdButton                matlab.ui.control.ToggleButton
        FormulaTextArea           matlab.ui.control.TextArea
        FormulaTextAreaLabel      matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            %[filename,path]=uigetfile();
            temp=readtable("Initiate Cold.xlsx");
            app.UITable.Data = temp;
            figure(app.UIFigure);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Value changed function: FormulaTextArea
        function FormulaTextAreaValueChanged(app, event)
            value = app.FormulaTextArea.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Selection changed function: StartupTypeButtonGroup
        function StartupTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.StartupTypeButtonGroup.SelectedObject;
            if app.ColdButton.Value==1
                temp=readtable("Labelled Data.xlsx",ReadRowNames=true,Sheet="Cold Start Sample");
                app.UITable.Data = temp;
            elseif app.Warm1Button.Value==1
                temp=readtable("Labelled Data.xlsx",ReadRowNames=true,Sheet="Warm I Start Sample");
                app.UITable.Data = temp;
            elseif app.Warm2Button.Value==1
                temp=readtable("Labelled Data.xlsx",ReadRowNames=true,Sheet="Warm IIStart Sample");
                app.UITable.Data = temp;
            elseif app.HotButton.Value==1
                temp=readtable("Labelled Data.xlsx",ReadRowNames=true,Sheet="Hot Start Sample");
                app.UITable.Data = temp;
            end
        end

        % Value changed function: TimeSavedsEditField
        function TimeSavedsEditFieldValueChanged(app, event)
            value = app.TimeSavedsEditField.Value;
        end

        % Selection changed function: SequenceButtonGroup
        function SequenceButtonGroupSelectionChanged(app, event)
            selectedButton = app.SequenceButtonGroup.SelectedObject;
            if app.PurgeButton.Value==1 && app.ColdButton.Value==1
                Purge_Cold_PSO;
                Purge_Cold;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
            elseif app.InitiateButton.Value==1 && app.ColdButton.Value==1
                Approx_cost;
                Initiate_Cold;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
            elseif app.FiringButton.Value==1 && app.ColdButton.Value==1
                Firing_Cold_PSO;
                Firing_Cold;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
            elseif app.WarmUpButton.Value==1 && app.ColdButton.Value==1
                WarmUp_Cold_PSO;
                WarmUp_Cold;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
            elseif app.SyncButton.Value==1 && app.ColdButton.Value==1
                cla(app.UIAxes);
                app.FormulaTextArea.Value = "No Data";
            elseif app.FSNLButton.Value==1 && app.ColdButton.Value==1
                cla(app.UIAxes);
                app.FormulaTextArea.Value = "No Data";
            elseif app.TempMatchButton.Value==1 && app.ColdButton.Value==1
                cla(app.UIAxes);
                app.FormulaTextArea.Value = "No Data";
            elseif app.IPCOnButton.Value==1 && app.ColdButton.Value==1
                cla(app.UIAxes);
                app.FormulaTextArea.Value = "No Data";
            elseif app.InitiateButton.Value==1 && app.Warm1Button.Value==1
                Initiate_Warm_1_PSO;
                Initiate_Warm_1;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
            elseif app.PurgeButton.Value==1 && app.Warm1Button.Value==1
                Purge_Warm1_PSO;
                Purge_Warm1;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
            elseif app.SyncButton.Value==1 && app.Warm1Button.Value==1
                Sync_Warm_PSO;
                Sync_Warm;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
            elseif app.InitiateButton.Value==1 && app.Warm2Button.Value==1
                Initiate_Warm_2_PSO;
                Initiate_Warm_2;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
            elseif app.PurgeButton.Value==1 && app.Warm2Button.Value==1
                Purge_Warm2_PSO;
                Purge_Warm2;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
             elseif app.InitiateButton.Value==1 && app.HotButton.Value==1
                Initiate_Hot_PSO;
                Initiate_Hot;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
             elseif app.PurgeButton.Value==1 && app.HotButton.Value==1
                Purge_Hot_PSO;
                Purge_Hot;
                cla(app.UIAxes);
                formula = coefficientString;
                app.FormulaTextArea.Value = formula;
                app.TimeSavedsEditField.Value=timesaved;
                y=curve;
                plot(app.UIAxes,timeline, y,'--or',timeline,dependent,'g',timeline,yFit,'b');
                title(app.UIAxes,"Power over Time");
                legend(app.UIAxes,'Optimized Data','Original Data','Fitted Curve');
                app.RSSEditField.Value = RSS;
                app.MeanErrorEditField.Value = MeanError;
            end
        end

        % Value changed function: RSSEditField
        function RSSEditFieldValueChanged(app, event)
            value = app.RSSEditField.Value;
        end

        % Value changed function: MeanErrorEditField
        function MeanErrorEditFieldValueChanged(app, event)
            value = app.MeanErrorEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Timeline')
            ylabel(app.UIAxes, 'Power')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 16'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.FormulaTextAreaLabel = uilabel(app.UIFigure);
            app.FormulaTextAreaLabel.Text = 'Formula';
            app.FormulaTextArea = uitextarea(app.UIFigure);
            app.FormulaTextArea.ValueChangedFcn = createCallbackFcn(app, @FormulaTextAreaValueChanged, true);
            app.StartupTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.StartupTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StartupTypeButtonGroupSelectionChanged, true);
            app.StartupTypeButtonGroup.Title = 'Start-up Type';
            app.ColdButton = uitogglebutton(app.StartupTypeButtonGroup);
            app.ColdButton.Text = 'Cold';
            app.ColdButton.Value = true;
            app.Warm1Button = uitogglebutton(app.StartupTypeButtonGroup);
            app.Warm1Button.Text = 'Warm 1';
            app.Warm2Button = uitogglebutton(app.StartupTypeButtonGroup);
            app.Warm2Button.Text = 'Warm 2';
            app.HotButton = uitogglebutton(app.StartupTypeButtonGroup);
            app.HotButton.Text = 'Hot';
            app.TimeSavedsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeSavedsEditFieldLabel.Text = 'Time Saved (s)';
            app.TimeSavedsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeSavedsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSavedsEditFieldValueChanged, true);
            app.SequenceButtonGroup = uibuttongroup(app.UIFigure);
            app.SequenceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SequenceButtonGroupSelectionChanged, true);
            app.SequenceButtonGroup.Title = 'Sequence';
            app.InitiateButton = uitogglebutton(app.SequenceButtonGroup);
            app.InitiateButton.Text = 'Initiate';
            app.InitiateButton.Value = true;
            app.PurgeButton = uitogglebutton(app.SequenceButtonGroup);
            app.PurgeButton.Text = 'Purge';
            app.FiringButton = uitogglebutton(app.SequenceButtonGroup);
            app.FiringButton.Text = 'Firing';
            app.SyncButton = uitogglebutton(app.SequenceButtonGroup);
            app.SyncButton.Text = 'Sync';
            app.FSNLButton = uitogglebutton(app.SequenceButtonGroup);
            app.FSNLButton.Text = 'FSNL';
            app.TempMatchButton = uitogglebutton(app.SequenceButtonGroup);
            app.TempMatchButton.Text = 'TempMatch';
            app.WarmUpButton = uitogglebutton(app.SequenceButtonGroup);
            app.WarmUpButton.Text = 'WarmUp';
            app.IPCOnButton = uitogglebutton(app.SequenceButtonGroup);
            app.IPCOnButton.Text = 'IPCOn';
            app.RSSEditFieldLabel = uilabel(app.UIFigure);
            app.RSSEditFieldLabel.Text = 'RSS';
            app.RSSEditField = uieditfield(app.UIFigure, 'numeric');
            app.RSSEditField.ValueChangedFcn = createCallbackFcn(app, @RSSEditFieldValueChanged, true);
            app.MeanErrorEditFieldLabel = uilabel(app.UIFigure);
            app.MeanErrorEditFieldLabel.Text = 'Mean Error';
            app.MeanErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MeanErrorEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end